classdef Controller_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        DataPathEditFieldLabel        matlab.ui.control.Label
        DataPathEditField             matlab.ui.control.EditField
        BrowseButton                  matlab.ui.control.Button
        ReconstructImagesButton       matlab.ui.control.Button
        SingleProcessingLabel         matlab.ui.control.Label
        SegmentImagesusingCNNorITKSnapLabel  matlab.ui.control.Label
        AnalyzeVentilationButton      matlab.ui.control.Button
        AnalyzeGasExchangeButton      matlab.ui.control.Button
        BulkProcessingLabel           matlab.ui.control.Label
        FolderContainingAllSubjectsEditFieldLabel  matlab.ui.control.Label
        FolderContainingAllSubjectsEditField  matlab.ui.control.EditField
        BrowseButton_2                matlab.ui.control.Button
        ReAnalyzeAllButton            matlab.ui.control.Button
        HealthyCohortTextAreaLabel    matlab.ui.control.Label
        HealthyCohortTextArea         matlab.ui.control.TextArea
        ReCheckforSegmentationButton  matlab.ui.control.Button
        ReProcessHealthyButton        matlab.ui.control.Button
        Label                         matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        TabGroup                      matlab.ui.container.TabGroup
        MontageTab                    matlab.ui.container.Tab
        UIAxes_2                      matlab.ui.control.UIAxes
        SlicesTab                     matlab.ui.container.Tab
        UIAxes                        matlab.ui.control.UIAxes
        SliceSlider_2Label            matlab.ui.control.Label
        SliceSlider_2                 matlab.ui.control.Slider
        SegmentationTab               matlab.ui.container.Tab
        UIAxes2                       matlab.ui.control.UIAxes
        ButtonGroup                   matlab.ui.container.ButtonGroup
        MontageButton                 matlab.ui.control.RadioButton
        SliceButton                   matlab.ui.control.RadioButton
        SliceSliderLabel              matlab.ui.control.Label
        SliceSlider                   matlab.ui.control.Slider
        ImageQCLabel                  matlab.ui.control.Label
        ImagetoDisplayDedicatedButtonGroup  matlab.ui.container.ButtonGroup
        VentilationButton             matlab.ui.control.RadioButton
        DissolvedButton               matlab.ui.control.RadioButton
        H1VentButton                  matlab.ui.control.RadioButton
        H1DisButton                   matlab.ui.control.RadioButton
        ImagetoDisplayAllinOneLabel   matlab.ui.control.Label
        VentilationButton_2           matlab.ui.control.RadioButton
        DissolvedButton_2             matlab.ui.control.RadioButton
        H1VentButton_2                matlab.ui.control.RadioButton
        H1DisButton_2                 matlab.ui.control.RadioButton
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Subject_Path; % Description
        AIO_Dis;
        AIO_Gas;
        AIO_HiGas;
        AIO_Vent;
        AIO_Anat_Vent;
        AIO_Anat_Dis;
        AIO_Cal;
        AIO_DisFID;
        AIO_GasFID;
        AIO_Params;
        AIO_DisTraj;
        Vent_Folders;
        GE_Folders;
        AIO_Folders;
        Im2Display;
        Seg_Present;
        Seg2Display;
        All_Path;
        HealthyCohort;
        Reprocess_Waitbar;
        Total_Subjects;
        Counter;
    end
    
    methods (Access = private)
        
        function check_single_path(app)
            if isnan(app.Subject_Path)
                app.ReconstructImagesButton.Enable = 'off';
            else
                app.ReconstructImagesButton.Enable = 'on';
            end
        end
        
        function find_available_images(app)
            if ~isnan(app.Subject_Path)
                files = dir(app.Subject_Path);
                % Get a logical vector that tells which is a directory.
                dirFlags = [files.isdir];
                % Extract only those that are directories.
                subFolders = files(dirFlags);
                Cell_Folders = struct2cell(subFolders);
                folder_names = Cell_Folders(1,:);
                %Find only subfolders that contain ventilation images
                app.Vent_Folders = find(contains(folder_names,'_Vent'));
                app.GE_Folders = find(contains(folder_names,'Dixon_Gas_Exchange'));
                %Just pick first one for simplicity... Can improve in future
                %iterations of app.
                if isempty(app.Vent_Folders)
                    app.VentilationButton.Enable = 'off';
                    app.H1VentButton.Enable = 'off';
                else
                    if length(app.Vent_Folders)>1
                        app.Vent_Folders = app.Vent_Folders{1};
                    end
                    app.Vent_Folders = folder_names{app.Vent_Folders};
                    app.VentilationButton.Enable = 'on';
                    app.H1VentButton.Enable = 'on';
                end
                if isempty(app.GE_Folders)
                    app.DissolvedButton.Enable = 'off';
                    app.H1DisButton.Enable = 'off';
                else
                    if length(app.GE_Folders)>1
                        app.GE_Folders = app.GE_Folders{1};     
                    end
                    app.GE_Folders = folder_names{app.GE_Folders};
                    app.DissolvedButton.Enable = 'on';
                    app.H1DisButton.Enable = 'on';
                end
                app.AIO_Folders = find(contains(folder_names,'All_in_One_Analysis'));
                if isempty(app.AIO_Folders)
                    app.VentilationButton_2.Enable = 'off';
                    app.H1VentButton_2.Enable = 'off';
                    app.DissolvedButton_2.Enable = 'off';
                    app.H1DisButton_2.Enable = 'off';
                else
                    if length(app.AIO_Folders)>1
                        app.AIO_Folders = app.AIO_Folders{1};
                    end
                    app.AIO_Folders = folder_names{app.AIO_Folders};
                    app.VentilationButton_2.Enable = 'on';
                    app.H1VentButton_2.Enable = 'on';
                    app.DissolvedButton_2.Enable = 'on';
                    app.H1DisButton_2.Enable = 'on';
                end
            else
                app.VentilationButton_2.Enable = 'off';
                app.H1VentButton_2.Enable = 'off';
                app.DissolvedButton_2.Enable = 'off';
                app.H1DisButton_2.Enable = 'off';
                app.DissolvedButton.Enable = 'off';
                app.H1DisButton.Enable = 'off';
                app.VentilationButton.Enable = 'off';
                app.H1VentButton.Enable = 'off';
            end
        end
        
        function display_Im(app)
            if app.TabGroup.SelectedTab == app.MontageTab
                montage(app.Im2Display,'Parent',app.UIAxes_2);
                app.UIAxes_2.CLim = [0 prctile(app.Im2Display(:),99)];
            elseif app.TabGroup.SelectedTab == app.SlicesTab
                imagesc(app.UIAxes,app.Im2Display(:,:,round(app.SliceSlider_2.Value)))
                app.UIAxes.Colormap = gray;
                app.UIAxes.XTick = [];
                app.UIAxes.YTick = [];
                app.UIAxes.CLim = [0 prctile(app.Im2Display(:),99)];
                axis(app.UIAxes,"square")
                axis(app.UIAxes,'off')
            elseif app.TabGroup.SelectedTab == app.SegmentationTab
                cmap = [0 0 0; 1 0 0];
                if app.MontageButton.Value
                    app.SliceSlider.Visible = 'off';
                %    app.SLiceSliderLabel.Visible = 'off';
                    [~,firstslice,lastslice] = Tools.getimcenter(app.Seg2Display);
                    Im_tile = Tools.tile_image(app.Im2Display(:,:,(firstslice-2):(lastslice-2)),3);
                    Seg_tile = Tools.tile_image(app.Seg2Display(:,:,(firstslice-2):(lastslice-2)),3);
                    Im_tile = Im_tile/prctile(Im_tile(:),99);
                    Seg_tile = Seg_tile/2;
                    %[~,~] = Tools.imoverlay(Im_tile,Seg_tile,[0.9,1.1],[0,0.99*prctile(app.Im2Display(:),99)],cmap,0.25,app.UIAxes2);
                    imshowpair(Im_tile,Seg_tile,'Parent',app.UIAxes2);
                   % colormap(app.UIAxes2,cmap);
                else
                    app.SliceSlider.Visible = 'on';
                  %  app.SLiceSliderLabel.Visible = 'on';
                    Im_slice = squeeze(app.Im2Display(:,:,round(app.SliceSlider.Value)));
                    Seg_slice = squeeze(app.Seg2Display(:,:,round(app.SliceSlider.Value)));
                    Im_slice = Im_slice/prctile(Im_slice(:),99);
                    Seg_slice = Seg_slice/2;
                    %[~,~] = Tools.imoverlay(Im_slice,Seg_slice,[0.9,1.1],[0,0.99*prctile(app.Im2Display(:),99)],cmap,0.25,app.UIAxes2);
                    imshowpair(Im_slice,Seg_slice,'Parent',app.UIAxes2);
                    %colormap(app.UIAxes2,cmap);
                end
            end
        end
        
        function update_slider(app)
            app.SliceSlider_2.Limits = [1 size(app.Im2Display,3)];
            app.SliceSlider_2.MinorTicks = [];
            app.SliceSlider.Limits = [1 size(app.Im2Display,3)];
            app.SliceSlider.MinorTicks = [];
        end
        
        function check_segmentation(app)
            seg_present = 1; %Start by saying segementation is there. Then check and change this variable if not
            if ~isempty(app.Vent_Folders)
                if ~isfile(fullfile(app.Vent_Folders,'Anatomic_Image_mask.nii.gz')) && ~isfile(fullfile(app.Vent_Folders,'Manual_Mask.nii.gz'))
                    seg_present = 0;
                end
            end
            if ~isempty(app.GE_Folders)
                if ~isfile(fullfile(app.GE_Folders,'Anatomic_Image_mask.nii.gz')) && ~isfile(fullfile(app.Vent_Folders,'Manual_Mask.nii.gz'))
                    seg_present = 0;
                end
            end
            if ~isempty(app.AIO_Folders)
                if ~isfile(fullfile(app.AIO_Folders,'HiRes_Anatomic_mask.nii.gz')) && ~isfile(fullfile(app.Vent_Folders,'Manual_Mask.nii.gz'))
                    seg_present = 0;
                end
            end
            if ~isempty(app.AIO_Folders)
                if ~isfile(fullfile(app.AIO_Folders,'LoRes_Anatomic_mask.nii.gz')) && ~isfile(fullfile(app.Vent_Folders,'Manual_Mask.nii.gz'))
                    seg_present = 0;
                end
            end
            if seg_present
               app.SegmentImagesusingCNNorITKSnapLabel.Visible = 'off'; 
               app.AnalyzeVentilationButton.Enable = 'on';
               app.AnalyzeGasExchangeButton.Enable = 'on';
               app.SegmentationTab.HandleVisibility = 'on';
               app.Seg_Present = 1;
            else
               app.SegmentImagesusingCNNorITKSnapLabel.Visible = 'on'; 
               app.AnalyzeVentilationButton.Enable = 'off';
               app.AnalyzeGasExchangeButton.Enable = 'off';
               app.Seg_Present = 0;
               app.SegmentationTab.HandleVisibility = 'off';
            end
            
        end
        
        function start_fresh(app)
            app.SegmentImagesusingCNNorITKSnapLabel.Visible = 'on'; 
            app.AnalyzeVentilationButton.Enable = 'off';
            app.AnalyzeGasExchangeButton.Enable = 'off';
            app.Subject_Path = nan;
            cla(app.UIAxes);
            cla(app.UIAxes_2);
            app.find_available_images();
            app.check_segmentation();
        end
        
        function get_healthy_cohort(app)
            app.HealthyCohort.GE = app.HealthyCohortTextArea.Value;
        end
        
        function reprocess_healthy(app)
            folders = dir(app.All_Path); %obtain directory
            numFolders = size(folders,1);
            HealthyErrors = 0;
            for i = 1:numFolders
                folder = folders(i).name;
                if contains(folder,'Xe-')
                    start_ind = strfind(folder,'Xe-');
                    subject = folder(start_ind:end);
                else
                    continue;
                end
                Healthy_YN = sum(contains(app.HealthyCohort.GE,subject)); %determine if matched any healthys
                if (Healthy_YN) %import data from folder if healthy
                    %Reprocess healthy data 
               %     waitbar(app.Counter/app.Total_Subjects,app.Reprocess_Waitbar,['Re-analyzing ' folder]);
                    kumc_xenon_pipeline(fullfile(app.All_Path,folder))
                    allinone_recon(fullfile(app.All_Path,folder));
                    app.Counter = app.Counter + 1;
                end
            end
        end
        
        function get_total_subjects(app)
            all_files = dir(app.All_Path);
            all_cell = struct2cell(all_files);
            myfiles = all_cell(1,:);
            xe_files = [myfiles(contains(myfiles,'Xe-'))];
            app.Total_Subjects = length(xe_files)+length(app.HealthyCohort.GE);
        end
        
        function generate_healthy_cohort(app)
            thresholds = Healthy.CreateHealthyCohort(app.All_Path,app.HealthyCohort,'20211119_HealthyCohort');
        end
        
        function reprocess_all(app)
            folders = dir(app.All_Path); %obtain directory
            numFolders = size(folders,1);
            for i = 1:numFolders
                folder = folders(i).name;
                if contains(folder,'Xe-')
             %       waitbar(app.Counter/app.Total_Subjects,app.Reprocess_Waitbar,['Re-analyzing ' folder]);
                    kumc_xenon_pipeline(fullfile(app.All_Path,folder));
                    allinone_recon(fullfile(app.All_Path,folder));
                    app.Counter = app.Counter+1;
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Subject_Path = nan; %Make sure initially can't press
            app.check_single_path();
            app.find_available_images();
            app.check_segmentation();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {500, 500};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {339, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            app.Subject_Path = uigetdir();
            cd(app.Subject_Path);
            app.DataPathEditField.Value = app.Subject_Path;
            app.check_single_path();
            app.find_available_images();
            app.check_segmentation();
        end

        % Button pushed function: ReconstructImagesButton
        function ReconstructImagesButtonPushed(app, event)
            [app.AIO_Dis,app.AIO_Gas,app.AIO_HiGas,app.AIO_Vent,app.AIO_Anat_Vent,app.AIO_Anat_Dis,app.AIO_Cal,app.AIO_DisFID,app.AIO_GasFID,app.AIO_Params,app.AIO_DisTraj] = all_in_one_read_recon();
            kumc_xenon_pipeline(app.Subject_Path,'All','Skip','Skip');
            app.find_available_images();
            app.check_segmentation();
        end

        % Button pushed function: AnalyzeVentilationButton
        function AnalyzeVentilationButtonPushed(app, event)
            kumc_xenon_pipeline(app.Subject_Path,'Skip','Skip','Vent');
            write_path = pwd;
            %write_path((end-3):end) = [];
            write_path = fullfile(write_path,'All_in_One_Analysis');
            VentMask = niftiread(fullfile(write_path,'HiRes_Anatomic_mask.nii.gz'));
            analyze_vent_images(write_path,app.AIO_Vent,app.AIO_Anat_Vent,VentMask,app.AIO_Params.scandatestr)
        end

        % Button pushed function: AnalyzeGasExchangeButton
        function AnalyzeGasExchangeButtonPushed(app, event)
            kumc_xenon_pipeline(app.Subject_Path,'Skip','Skip','GE');
            write_path = pwd;
            %write_path((end-3):end) = [];
            write_path = fullfile(write_path,'All_in_One_Analysis');
            DisMask= niftiread(fullfile(write_path,'LoRes_Anatomic_mask.nii.gz'));
            analyze_ge_images(app.AIO_Dis,app.AIO_Gas,app.AIO_HiGas,app.AIO_Anat_Dis,app.AIO_Cal,DisMask,write_path,app.AIO_DisFID,app.AIO_GasFID,app.AIO_Params,app.AIO_DisTraj);
        end

        % Selection changed function: 
        % ImagetoDisplayDedicatedButtonGroup
        function ImagetoDisplayDedicatedButtonGroupSelectionChanged(app, event)
            selectedButton = app.ImagetoDisplayDedicatedButtonGroup.SelectedObject;
            if app.VentilationButton.Value
                app.Im2Display = niftiread(fullfile(app.Vent_Folders,'Vent_Image.nii.gz'));
                if isfile(fullfile(app.Vent_Folders,'Manual_Mask.nii.gz'))
                    app.Seg2Display = niftiread(fullfile(app.Vent_Folders,'Manual_Mask.nii.gz'));
                elseif isfile(fullfile(app.Vent_Folders,'Anatomic_Image_mask.nii.gz'))
                    app.Seg2Display = niftiread(fullfile(app.Vent_Folders,'Anatomic_Image_mask.nii.gz'));
                end
            elseif app.DissolvedButton.Value
                app.Im2Display = niftiread(fullfile(app.GE_Folders,'TotalDissolved.nii.gz'));
                if isfile(fullfile(app.Vent_Folders,'Manual_Mask.nii.gz'))
                    app.Seg2Display = niftiread(fullfile(app.GE_Folders,'Manual_Mask.nii.gz'));
                elseif isfile(fullfile(app.GE_Folders,'Anatomic_Image_mask.nii.gz'))
                    app.Seg2Display = niftiread(fullfile(app.GE_Folders,'Anatomic_Image_mask.nii.gz'));
                end
            elseif app.H1VentButton.Value
                app.Im2Display = niftiread(fullfile(app.Vent_Folders,'Anatomic_Image.nii.gz'));
                if isfile(fullfile(app.Vent_Folders,'Manual_Mask.nii.gz'))
                    app.Seg2Display = niftiread(fullfile(app.Vent_Folders,'Manual_Mask.nii.gz'));
                elseif isfile(fullfile(app.Vent_Folders,'Anatomic_Image_mask.nii.gz'))
                    app.Seg2Display = niftiread(fullfile(app.Vent_Folders,'Anatomic_Image_mask.nii.gz'));
                end
            elseif app.H1DisButton.Value
                app.Im2Display = niftiread(fullfile(app.GE_Folders,'Anatomic_Image.nii.gz'));
                if isfile(fullfile(app.Vent_Folders,'Manual_Mask.nii.gz'))
                    app.Seg2Display = niftiread(fullfile(app.GE_Folders,'Manual_Mask.nii.gz'));
                elseif isfile(fullfile(app.GE_Folders,'Anatomic_Image_mask.nii.gz'))
                    app.Seg2Display = niftiread(fullfile(app.GE_Folders,'Anatomic_Image_mask.nii.gz'));
                end
            elseif app.VentilationButton_2.Value
                app.Im2Display = niftiread(fullfile(app.AIO_Folders,'Vent_Image.nii.gz'));
                if isfile(fullfile(app.AIO_Folders,'HiRes_Manual_Mask.nii.gz'))
                    app.Seg2Display = niftiread(fullfile(app.AIO_Folders,'HiRes_Manual_Mask.nii.gz'));
                elseif isfile(fullfile(app.AIO_Folders,'HiRes_Anatomic_mask.nii.gz'))
                    app.Seg2Display = niftiread(fullfile(app.AIO_Folders,'HiRes_Anatomic_mask.nii.gz'));
                end
            elseif app.DissolvedButton_2.Value
                app.Im2Display = niftiread(fullfile(app.AIO_Folders,'Dissolved_Image.nii.gz'));
                if isfile(fullfile(app.AIO_Folders,'LoRes_Manual_Mask.nii.gz'))
                    app.Seg2Display = niftiread(fullfile(app.AIO_Folders,'LoRes_Manual_Mask.nii.gz'));
                elseif isfile(fullfile(app.AIO_Folders,'LoRes_Anatomic_mask.nii.gz'))
                    app.Seg2Display = niftiread(fullfile(app.AIO_Folders,'LoRes_Anatomic_mask.nii.gz'));
                end
            elseif app.H1VentButton_2.Value
                app.Im2Display = niftiread(fullfile(app.AIO_Folders,'HiRes_Anatomic.nii.gz'));
                if isfile(fullfile(app.AIO_Folders,'HiRes_Manual_Mask.nii.gz'))
                    app.Seg2Display = niftiread(fullfile(app.AIO_Folders,'HiRes_Manual_Mask.nii.gz'));
                elseif isfile(fullfile(app.AIO_Folders,'HiRes_Anatomic_mask.nii.gz'))
                    app.Seg2Display = niftiread(fullfile(app.AIO_Folders,'HiRes_Anatomic_mask.nii.gz'));
                end
            elseif app.H1DisButton_2.Value 
                app.Im2Display = niftiread(fullfile(app.AIO_Folders,'LoRes_Anatomic.nii.gz'));
                if isfile(fullfile(app.AIO_Folders,'LoRes_Manual_Mask.nii.gz'))
                    app.Seg2Display = niftiread(fullfile(app.AIO_Folders,'LoRes_Manual_Mask.nii.gz'));
                elseif isfile(fullfile(app.AIO_Folders,'LoRes_Anatomic_mask.nii.gz'))
                    app.Seg2Display = niftiread(fullfile(app.AIO_Folders,'LoRes_Anatomic_mask.nii.gz'));
                end
            end
            app.Im2Display = double(app.Im2Display);
            app.Seg2Display = double(app.Seg2Display);
            app.Im2Display = Tools.canonical2matlab(app.Im2Display);
            app.Seg2Display = Tools.canonical2matlab(app.Seg2Display);
            app.update_slider();
            app.display_Im();
        end

        % Value changed function: SliceSlider_2
        function SliceSlider_2ValueChanged(app, event)
            value = app.SliceSlider_2.Value;
            app.display_Im();
        end

        % Value changed function: DataPathEditField
        function DataPathEditFieldValueChanged(app, event)
            app.start_fresh();
            app.Subject_Path = app.DataPathEditField.Value;
            app.check_single_path();
            app.find_available_images();
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            if selectedTab == app.SegmentationTab && app.Seg_Present == 0
                app.TabGroup.SelectedTab = event.OldValue;
            end
            app.display_Im();    
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            app.display_Im(); 
        end

        % Value changed function: SliceSlider
        function SliceSliderValueChanged(app, event)
            app.display_Im(); 
        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
            app.All_Path = uigetdir();
            app.FolderContainingAllSubjectsEditField.Value = app.All_Path;
            app.get_healthy_cohort();
            app.get_total_subjects();
        end

        % Button pushed function: ReAnalyzeAllButton
        function ReAnalyzeAllButtonPushed(app, event)
            %app.Reprocess_Waitbar = waitbar(0,'Beginning Reprocessing of all subjects');
            app.Counter = 0;
            app.reprocess_healthy();
            app.generate_healthy_cohort();
            app.reprocess_all();
        end

        % Value changed function: 
        % FolderContainingAllSubjectsEditField
        function FolderContainingAllSubjectsEditFieldValueChanged(app, event)
            app.All_Path = app.FolderContainingAllSubjectsEditField.Value;
            app.get_healthy_cohort();
            app.get_total_subjects();
        end

        % Button pushed function: ReProcessHealthyButton
        function ReProcessHealthyButtonPushed(app, event)
            app.Total_Subjects = length(app.HealthyCohort.GE);
           % app.Reprocess_Waitbar = waitbar(0,'Beginning Reprocessing of all Healthy');
            app.Counter = 0;
            app.reprocess_healthy();
            app.generate_healthy_cohort();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DataPathEditFieldLabel = uilabel(app.LeftPanel);
            app.DataPathEditFieldLabel.Text = 'Data Path';
            app.DataPathEditField = uieditfield(app.LeftPanel, 'text');
            app.DataPathEditField.ValueChangedFcn = createCallbackFcn(app, @DataPathEditFieldValueChanged, true);
            app.BrowseButton = uibutton(app.LeftPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.ReconstructImagesButton = uibutton(app.LeftPanel, 'push');
            app.ReconstructImagesButton.ButtonPushedFcn = createCallbackFcn(app, @ReconstructImagesButtonPushed, true);
            app.ReconstructImagesButton.Enable = 'off';
            app.ReconstructImagesButton.Text = 'Reconstruct Images';
            app.SingleProcessingLabel = uilabel(app.LeftPanel);
            app.SingleProcessingLabel.Text = 'Single Processing';
            app.SegmentImagesusingCNNorITKSnapLabel = uilabel(app.LeftPanel);
            app.SegmentImagesusingCNNorITKSnapLabel.Text = 'Segment Images using CNN or ITK Snap';
            app.AnalyzeVentilationButton = uibutton(app.LeftPanel, 'push');
            app.AnalyzeVentilationButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeVentilationButtonPushed, true);
            app.AnalyzeVentilationButton.Text = 'Analyze Ventilation';
            app.AnalyzeGasExchangeButton = uibutton(app.LeftPanel, 'push');
            app.AnalyzeGasExchangeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeGasExchangeButtonPushed, true);
            app.AnalyzeGasExchangeButton.Text = 'Analyze Gas Exchange';
            app.BulkProcessingLabel = uilabel(app.LeftPanel);
            app.BulkProcessingLabel.Text = 'Bulk Processing';
            app.FolderContainingAllSubjectsEditFieldLabel = uilabel(app.LeftPanel);
            app.FolderContainingAllSubjectsEditFieldLabel.Text = 'Folder Containing All Subjects';
            app.FolderContainingAllSubjectsEditField = uieditfield(app.LeftPanel, 'text');
            app.FolderContainingAllSubjectsEditField.ValueChangedFcn = createCallbackFcn(app, @FolderContainingAllSubjectsEditFieldValueChanged, true);
            app.BrowseButton_2 = uibutton(app.LeftPanel, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Text = 'Browse';
            app.ReAnalyzeAllButton = uibutton(app.LeftPanel, 'push');
            app.ReAnalyzeAllButton.ButtonPushedFcn = createCallbackFcn(app, @ReAnalyzeAllButtonPushed, true);
            app.ReAnalyzeAllButton.Text = 'Re-Analyze All';
            app.HealthyCohortTextAreaLabel = uilabel(app.LeftPanel);
            app.HealthyCohortTextAreaLabel.Text = 'Healthy Cohort';
            app.HealthyCohortTextArea = uitextarea(app.LeftPanel);
            app.HealthyCohortTextArea.Value = {'Xe-015'; 'Xe-017'; 'Xe-014'; 'Xe-016'; 'Xe-021'; 'Xe-022'};
            app.ReCheckforSegmentationButton = uibutton(app.LeftPanel, 'push');
            app.ReCheckforSegmentationButton.Text = 'Re-Check for Segmentation';
            app.ReProcessHealthyButton = uibutton(app.LeftPanel, 'push');
            app.ReProcessHealthyButton.ButtonPushedFcn = createCallbackFcn(app, @ReProcessHealthyButtonPushed, true);
            app.ReProcessHealthyButton.Text = 'Re-Process Healthy';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = 'Note: Re-Processing does not make new masks if run from a Windows environment';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.MontageTab = uitab(app.TabGroup);
            app.MontageTab.Title = 'Montage';
            app.UIAxes_2 = uiaxes(app.MontageTab);
            title(app.UIAxes_2, 'Image Montage')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.SlicesTab = uitab(app.TabGroup);
            app.SlicesTab.Title = 'Slices';
            app.UIAxes = uiaxes(app.SlicesTab);
            title(app.UIAxes, 'Image Slice')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.SliceSlider_2Label = uilabel(app.SlicesTab);
            app.SliceSlider_2Label.Text = 'Slice';
            app.SliceSlider_2 = uislider(app.SlicesTab);
            app.SliceSlider_2.ValueChangedFcn = createCallbackFcn(app, @SliceSlider_2ValueChanged, true);
            app.SegmentationTab = uitab(app.TabGroup);
            app.SegmentationTab.Title = 'Segmentation';
            app.UIAxes2 = uiaxes(app.SegmentationTab);
            title(app.UIAxes2, 'Check Segmentation')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.ButtonGroup = uibuttongroup(app.SegmentationTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.MontageButton = uiradiobutton(app.ButtonGroup);
            app.MontageButton.Text = 'Montage';
            app.MontageButton.Value = true;
            app.SliceButton = uiradiobutton(app.ButtonGroup);
            app.SliceButton.Text = 'Slice';
            app.SliceSliderLabel = uilabel(app.SegmentationTab);
            app.SliceSliderLabel.Text = 'Slice';
            app.SliceSlider = uislider(app.SegmentationTab);
            app.SliceSlider.ValueChangedFcn = createCallbackFcn(app, @SliceSliderValueChanged, true);
            app.ImageQCLabel = uilabel(app.RightPanel);
            app.ImageQCLabel.Text = 'Image QC';
            app.ImagetoDisplayDedicatedButtonGroup = uibuttongroup(app.RightPanel);
            app.ImagetoDisplayDedicatedButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ImagetoDisplayDedicatedButtonGroupSelectionChanged, true);
            app.ImagetoDisplayDedicatedButtonGroup.Title = 'Image to Display - Dedicated';
            app.VentilationButton = uiradiobutton(app.ImagetoDisplayDedicatedButtonGroup);
            app.VentilationButton.Text = 'Ventilation';
            app.DissolvedButton = uiradiobutton(app.ImagetoDisplayDedicatedButtonGroup);
            app.DissolvedButton.Text = 'Dissolved';
            app.DissolvedButton.Value = true;
            app.H1VentButton = uiradiobutton(app.ImagetoDisplayDedicatedButtonGroup);
            app.H1VentButton.Text = 'H1 (Vent)';
            app.H1DisButton = uiradiobutton(app.ImagetoDisplayDedicatedButtonGroup);
            app.H1DisButton.Text = 'H1 (Dis)';
            app.ImagetoDisplayAllinOneLabel = uilabel(app.ImagetoDisplayDedicatedButtonGroup);
            app.ImagetoDisplayAllinOneLabel.Text = 'Image to Display - All in One';
            app.VentilationButton_2 = uiradiobutton(app.ImagetoDisplayDedicatedButtonGroup);
            app.VentilationButton_2.Text = 'Ventilation';
            app.DissolvedButton_2 = uiradiobutton(app.ImagetoDisplayDedicatedButtonGroup);
            app.DissolvedButton_2.Text = 'Dissolved';
            app.H1VentButton_2 = uiradiobutton(app.ImagetoDisplayDedicatedButtonGroup);
            app.H1VentButton_2.Text = 'H1 (Vent)';
            app.H1DisButton_2 = uiradiobutton(app.ImagetoDisplayDedicatedButtonGroup);
            app.H1DisButton_2.Text = 'H1 (Dis)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Controller_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end